classdef WECBenchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DeviceUnderTestPanel         matlab.ui.container.Panel
        WECBenchPanel                matlab.ui.container.Panel
        AppControlPanel              matlab.ui.container.Panel
        RampDownButton               matlab.ui.control.Button
        RampUpButton                 matlab.ui.control.Button
        TrialSpinner                 matlab.ui.control.Spinner
        TrialSpinnerLabel            matlab.ui.control.Label
        ClearFaultsButton            matlab.ui.control.Button
        EnableDriveButton            matlab.ui.control.Button
        StartStopButton              matlab.ui.control.Button
        CurrentLimitSpinner          matlab.ui.control.Spinner
        CurrentLimitSpinnerLabel     matlab.ui.control.Label
        ExperimentEditField          matlab.ui.control.EditField
        ExperimentEditFieldLabel     matlab.ui.control.Label
        ProjectEditField             matlab.ui.control.EditField
        ProjectEditFieldLabel        matlab.ui.control.Label
        ModelParametersPanel         matlab.ui.container.Panel
        eCATStateEditField           matlab.ui.control.NumericEditField
        eCATStateEditFieldLabel      matlab.ui.control.Label
        HWRLShareSwitch              matlab.ui.control.Switch
        HWRLShareSwitchLabel         matlab.ui.control.Label
        tgNameLabel                  matlab.ui.control.Label
        timesEditField               matlab.ui.control.NumericEditField
        timesEditFieldLabel          matlab.ui.control.Label
        CLLabel                      matlab.ui.control.Label
        TsLabel                      matlab.ui.control.Label
        ModelNameLabel               matlab.ui.control.Label
        DrivePanel                   matlab.ui.container.Panel
        ReferenceSignalsPanel        matlab.ui.container.Panel
        SinePeriodsecEditField       matlab.ui.control.NumericEditField
        SinePeriodsecEditFieldLabel  matlab.ui.control.Label
        SineAmpdegEditField          matlab.ui.control.NumericEditField
        SineAmpdegEditFieldLabel     matlab.ui.control.Label
        ModeDropDown                 matlab.ui.control.DropDown
        ModeDropDownLabel            matlab.ui.control.Label
        positionAxes                 matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        buildDir
        mdlName
        tg
        tgName
        hInst
        decimationAxes
        decimation
        Ts
        CL
        startIcon = fullfile('utils','run_24.png')
        stopIcon = fullfile('utils','stop_24.png')    
        TimeCheckTimer
    end
    
    methods (Access = private)
        
        function setupInstrumentation(app)
            app.tg.removeAllInstruments;
            app.hInst = slrealtime.Instrument(app.mdlName);
            %% Scalars
            app.hInst.connectScalar(app.timesEditField,[app.mdlName,'/Data Logging/Clock'],1,'Decimation',app.decimation)            
            app.hInst.connectScalar(app.eCATStateEditField,[app.mdlName,'/EtherCAT Init/eCATstate'],1,'Decimation',app.decimation)
            %% Axes
            app.hInst.connectLine(app.positionAxes,[app.mdlName,'/Drive Measurements/pos_deg'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.positionAxes,[app.mdlName,'/Drive Commands/pos_cmd_deg'],1,'Decimation',app.decimationAxes)
            legend(app.positionAxes,'measured','commanded')


            app.tg.addInstrument(app.hInst)          
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'WECBench';
            drawnow;
            app.UIFigure.WindowState = 'maximized';

            app.mdlName = evalin('base','mdlName');
            modelVersion = evalin('base','mdlVersion');
            app.ModelNameLabel.Text = ['Model: ',app.mdlName,' Version:',modelVersion];

            app.decimation = evalin('base','appDecimation');
            app.decimationAxes = evalin('base','appDecimationAxes');
            
            app.Ts = evalin('base','Ts');
            app.TsLabel.Text = ['Ts = ',num2str(app.Ts),' s'];
            app.CL = evalin('base','CL');
            app.CLLabel.Text = ['Current Limit: ',num2str(app.CL),' A'];
            app.HWRLShareSwitch.Value = 'No'; % change this to change default behavior
            app.RampDownButton.Enable = 'off';


            app.tgName = evalin('base','tgName');
            app.tgNameLabel.Text = [app.tgName];

            app.tg = slrealtime(app.tgName);
            app.tg.stop;
            app.tg.removeAllInstruments();

            app.buildDir = evalin('base','buildDir');
            app.tg.load(fullfile(app.buildDir,app.mdlName));

            app.StartStopButton.Icon = app.startIcon;
            app.StartStopButton.Text = 'Start';

            app.EnableDriveButton.Text = 'Enable Drive';
            app.EnableDriveButton.BackgroundColor = 'Red';

            app.ClearFaultsButton.Text = 'Clear Faults';

            app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'RisingSlewLimit',1e6)
            app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'FallingSlewLimit',-1e6)

            app.setupInstrumentation;
            
        
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
             if strcmp(app.StartStopButton.Icon,app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StartStopButton.Icon = app.stopIcon;
                app.StartStopButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StartStopButton.Icon = app.startIcon;
                app.StartStopButton.Text = 'Start';
                run('postProcess.m')
            end           
        end

        % Value changed function: HWRLShareSwitch
        function HWRLShareSwitchValueChanged(app, event)
            % value = app.HWRLShareSwitch.Value;
            
        end

        % Button pushed function: EnableDriveButton
        function EnableDriveButtonPushed(app, event)
            if strcmp(app.EnableDriveButton.Text,'Enable Drive')
                app.tg.setparam([app.mdlName,'/Drive Init/enableDrive'],'Value',1);
                app.EnableDriveButton.Text = 'Drive Enabled';
                app.EnableDriveButton.BackgroundColor = 'Green';
            else
                app.tg.setparam([app.mdlName,'/Drive Init/enableDrive'],'Value',0);
                app.EnableDriveButton.Text = 'Enable Drive';
                app.EnableDriveButton.BackgroundColor = 'Red';
            end
        end

        % Button pushed function: ClearFaultsButton
        function ClearFaultsButtonPushed(app, event)
            if strcmp(app.ClearFaultsButton.Text,'Clear Faults')
                app.tg.setparam([app.mdlName,'/Drive Init/clearFaults'],'Value',1)
                app.ClearFaultsButton.Text = 'Faults Clearing';
                pause(1);
                app.tg.setparam([app.mdlName,'/Drive Init/clearFaults'],'Value',0)
                app.ClearFaultsButton.Text = 'Clear Faults';
            end
        end

        % Button pushed function: RampUpButton
        function RampUpButtonPushed(app, event)
            if strcmp(app.RampUpButton.Text,'Ramp Up')
                app.tg.setparam([app.mdlName,'/Drive Commands/Ramps/EnableRamps'],'Value',1);
                app.RampUpButton.Text = 'Ramped';
                app.RampUpButton.Enable = 'off';

                app.RampDownButton.Enable = 'on';
                app.RampDownButton.Text = 'Ramp Down';

                app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'RisingSlewLimit',1e7)
                app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'FallingSlewLimit',-1e7)
                
            end
        end

        % Button pushed function: RampDownButton
        function RampDownButtonPushed(app, event)
            if strcmp(app.RampDownButton.Text,'Ramp Down')
                app.tg.setparam([app.mdlName,'/Drive Commands/Ramps/EnableRamps'],'Value',0);
                app.RampDownButton.Text = 'Ramped';
                app.RampDownButton.Enable = 'off';

                app.RampUpButton.Text = 'Ramp Up';
                app.RampUpButton.Enable = 'on';
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/Drive Commands/SelectMode'],'Value',1)
            elseif strcmp(value,'Custom')
                app.tg.setparam([app.mdlName,'/Drive Commands/SelectMode'],'Value',2)
            end
        end

        % Value changed function: SineAmpdegEditField
        function SineAmpdegEditFieldValueChanged(app, event)
            value = app.SineAmpdegEditField.Value;
            app.tg.setparam([app.mdlName,'/Drive Commands/Sine Wave'],'Amplitude',value*pi/180);
        end

        % Value changed function: SinePeriodsecEditField
        function SinePeriodsecEditFieldValueChanged(app, event)
            value = app.SinePeriodsecEditField.Value;
            app.tg.setparam([app.mdlName,'/Drive Commands/Sine Wave'],'Frequency',1/value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DrivePanel = uipanel(app.UIFigure);
            app.DrivePanel.Title = 'Drive';
            app.positionAxes = uiaxes(app.DrivePanel);
            title(app.positionAxes, 'Position')
            xlabel(app.positionAxes, 'time (s)')
            ylabel(app.positionAxes, 'pos (deg)')
            zlabel(app.positionAxes, 'Z')
            app.ReferenceSignalsPanel = uipanel(app.DrivePanel);
            app.ReferenceSignalsPanel.Title = 'Reference Signals';
            app.ModeDropDownLabel = uilabel(app.ReferenceSignalsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ReferenceSignalsPanel);
            app.ModeDropDown.Items = {'Sine', 'Custom', 'Option 3', 'Option 4'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Sine';
            app.SineAmpdegEditFieldLabel = uilabel(app.ReferenceSignalsPanel);
            app.SineAmpdegEditFieldLabel.Text = 'Sine Amp (deg)';
            app.SineAmpdegEditField = uieditfield(app.ReferenceSignalsPanel, 'numeric');
            app.SineAmpdegEditField.Limits = [0 360];
            app.SineAmpdegEditField.ValueChangedFcn = createCallbackFcn(app, @SineAmpdegEditFieldValueChanged, true);
            app.SineAmpdegEditField.Value = 15;
            app.SinePeriodsecEditFieldLabel = uilabel(app.ReferenceSignalsPanel);
            app.SinePeriodsecEditFieldLabel.Text = 'Sine Period (sec)';
            app.SinePeriodsecEditField = uieditfield(app.ReferenceSignalsPanel, 'numeric');
            app.SinePeriodsecEditField.Limits = [0 10];
            app.SinePeriodsecEditField.ValueChangedFcn = createCallbackFcn(app, @SinePeriodsecEditFieldValueChanged, true);
            app.SinePeriodsecEditField.Value = 5;
            app.WECBenchPanel = uipanel(app.UIFigure);
            app.WECBenchPanel.Title = 'WEC-Bench';
            app.ModelParametersPanel = uipanel(app.WECBenchPanel);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.ModelNameLabel = uilabel(app.ModelParametersPanel);
            app.ModelNameLabel.Text = 'ModelName';
            app.TsLabel = uilabel(app.ModelParametersPanel);
            app.TsLabel.Text = 'Ts';
            app.CLLabel = uilabel(app.ModelParametersPanel);
            app.CLLabel.Text = 'CL';
            app.timesEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.timesEditFieldLabel.Text = 'time (s)';
            app.timesEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.tgNameLabel = uilabel(app.ModelParametersPanel);
            app.tgNameLabel.Text = 'tgName';
            app.HWRLShareSwitchLabel = uilabel(app.ModelParametersPanel);
            app.HWRLShareSwitchLabel.Text = 'HWRL Share';
            app.HWRLShareSwitch = uiswitch(app.ModelParametersPanel, 'slider');
            app.HWRLShareSwitch.Items = {'No', 'Yes'};
            app.HWRLShareSwitch.ValueChangedFcn = createCallbackFcn(app, @HWRLShareSwitchValueChanged, true);
            app.HWRLShareSwitch.Value = 'No';
            app.eCATStateEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.eCATStateEditFieldLabel.Text = 'eCAT State';
            app.eCATStateEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.AppControlPanel = uipanel(app.WECBenchPanel);
            app.AppControlPanel.Title = 'App Control';
            app.ProjectEditFieldLabel = uilabel(app.AppControlPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.AppControlPanel, 'text');
            app.ProjectEditField.Value = 'WECBench';
            app.ExperimentEditFieldLabel = uilabel(app.AppControlPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.AppControlPanel, 'text');
            app.CurrentLimitSpinnerLabel = uilabel(app.AppControlPanel);
            app.CurrentLimitSpinnerLabel.Text = 'Current Limit';
            app.CurrentLimitSpinner = uispinner(app.AppControlPanel);
            app.CurrentLimitSpinner.Limits = [0 Inf];
            app.StartStopButton = uibutton(app.AppControlPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'StartStop';
            app.EnableDriveButton = uibutton(app.AppControlPanel, 'push');
            app.EnableDriveButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDriveButtonPushed, true);
            app.EnableDriveButton.Text = 'Enable Drive';
            app.ClearFaultsButton = uibutton(app.AppControlPanel, 'push');
            app.ClearFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFaultsButtonPushed, true);
            app.ClearFaultsButton.Text = 'Clear Faults';
            app.TrialSpinnerLabel = uilabel(app.AppControlPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.AppControlPanel);
            app.TrialSpinner.Value = 1;
            app.RampUpButton = uibutton(app.AppControlPanel, 'push');
            app.RampUpButton.ButtonPushedFcn = createCallbackFcn(app, @RampUpButtonPushed, true);
            app.RampUpButton.Text = 'Ramp Up';
            app.RampDownButton = uibutton(app.AppControlPanel, 'push');
            app.RampDownButton.ButtonPushedFcn = createCallbackFcn(app, @RampDownButtonPushed, true);
            app.RampDownButton.Text = 'Ramp Down';
            app.DeviceUnderTestPanel = uipanel(app.UIFigure);
            app.DeviceUnderTestPanel.Title = 'Device Under Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WECBenchApp

            % Associate the Simulink Model
            app.Simulation = simulation('WECBench');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end